classdef RobotArm6Dof < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Panel          matlab.ui.container.Panel
        Label          matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Label_2        matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
        Label_3        matlab.ui.control.Label
        Slider_3       matlab.ui.control.Slider
        Label_4        matlab.ui.control.Label
        Slider_4       matlab.ui.control.Slider
        Panel_2        matlab.ui.container.Panel
        Label_5        matlab.ui.control.Label
        Label_6        matlab.ui.control.Label
        Slider_5       matlab.ui.control.Slider
        Label_7        matlab.ui.control.Label
        Slider_6       matlab.ui.control.Slider
        ZSliderLabel   matlab.ui.control.Label
        ZSlider        matlab.ui.control.Slider
        YSliderLabel   matlab.ui.control.Label
        YSlider        matlab.ui.control.Slider
        XSliderLabel   matlab.ui.control.Label
        XSlider        matlab.ui.control.Slider
        COMPanel       matlab.ui.container.Panel
        DropDownLabel  matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        Button         matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Lamp           matlab.ui.control.Lamp
        UITable        matlab.ui.control.Table
        Button_3       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduinoSerial; % Description
        th1Servo;
        th2Servo;
        th3Servo;
        th4Servo;
        rotateServo;
        grabServo;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try 
                switch app.DropDown.Value
                    case 1
                        app.arduinoSerial = arduino('COM1', 'Uno', 'Libraries', 'Servo');
                        app.th1Servo = servo(app.arduinoSerial, 'A0');
                        app.th2Servo = servo(app.arduinoSerial, 'A1');
                        app.th3Servo = servo(app.arduinoSerial, 'A2');
                        app.th4Servo = servo(app.arduinoSerial, 'A3');
                        app.rotateServo = servo(app.arduinoSerial, 'A4');
                        app.grabServo = servo(app.arduinoSerial, 'A5');
                    case 2
                        app.arduinoSerial = arduino('COM5', 'Uno', 'Libraries', 'Servo')
                        app.th1Servo = servo(app.arduinoSerial, 'A0');
                        app.th2Servo = servo(app.arduinoSerial, 'A1');
                        app.th3Servo = servo(app.arduinoSerial, 'A2');
                        app.th4Servo = servo(app.arduinoSerial, 'A3');
                        app.rotateServo = servo(app.arduinoSerial, 'A4');
                        app.grabServo = servo(app.arduinoSerial, 'A5');
                    case 3
                        app.arduinoSerial = arduino('COM3', 'Uno', 'Libraries', 'Servo');
                        app.th1Servo = servo(a, 'A0');
                        app.th2Servo = servo(a, 'A1');
                        app.th3Servo = servo(a, 'A2');
                        app.th4Servo = servo(a, 'A3');
                        app.rotateServo = servo(a, 'A4');
                        app.grabServo = servo(a, 'A5');
                    case 4
                        app.arduinoSerial = arduino('COM4', 'Uno', 'Libraries', 'Servo');
                        app.th1Servo = servo(a, 'A0');
                        app.th2Servo = servo(a, 'A1');
                        app.th3Servo = servo(a, 'A2');
                        app.th4Servo = servo(a, 'A3');
                        app.rotateServo = servo(a, 'A4');
                        app.grabServo = servo(a, 'A5');
                    case 5 
                        app.arduinoSerial = arduino('COM5', 'Uno', 'Libraries', 'Servo');
                        app.th1Servo = servo(a, 'A0');
                        app.th2Servo = servo(a, 'A1');
                        app.th3Servo = servo(a, 'A2');
                        app.th4Servo = servo(a, 'A3');
                        app.rotateServo = servo(a, 'A4');
                        app.grabServo = servo(a, 'A5');
                end 
                app.Lamp.Color = 'green';
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';  
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            try
                theta11 =  app.Slider.Value/180;
                writePosition(app.th1Servo, theta11);
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';  
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            try
                theta22 =  app.Slider_2.Value/180;
                writePosition(app.th2Servo, theta22);
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';  
            end    
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            theta33 =  app.Slider_3.Value/180
            writePosition(app.th3Servo, theta33);
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            theta44 =  app.Slider_4.Value/180
            writePosition(app.th4Servo, theta44);
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            rotate =  app.Slider_5.Value/180
            writePosition(app.rotateServo, rotate);
            
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            grab =  app.Slider_6.Value/180
            writePosition(app.grabServo, grab);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.arduinoSerial = ' ';
            app.th1Servo= ' ';
            app.th2Servo= ' ';
            app.th3Servo= ' ';
            app.th4Servo= ' ';
            app.rotateServo= ' ';
            app.grabServo= ' ';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Управление';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'θ1';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [0 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'θ2';
            app.Slider_2 = uislider(app.Panel);
            app.Slider_2.Limits = [0 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'θ3';
            app.Slider_3 = uislider(app.Panel);
            app.Slider_3.Limits = [0 180];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'θ4';
            app.Slider_4 = uislider(app.Panel);
            app.Slider_4.Limits = [0 180];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Panel_2 = uipanel(app.Panel);
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = 'Выходное звено';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = 'Вращение';
            app.Slider_5 = uislider(app.Panel_2);
            app.Slider_5.Limits = [0 180];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = 'Захват';
            app.Slider_6 = uislider(app.Panel_2);
            app.Slider_6.Limits = [0 180];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.ZSliderLabel = uilabel(app.Panel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.Panel);
            app.YSliderLabel = uilabel(app.Panel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.Panel);
            app.XSliderLabel = uilabel(app.Panel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.Panel);
            app.COMPanel = uipanel(app.UIFigure);
            app.COMPanel.Title = 'Подключение к COM порту';
            app.DropDownLabel = uilabel(app.COMPanel);
            app.DropDownLabel.Text = 'Порт';
            app.DropDown = uidropdown(app.COMPanel);
            app.DropDown.Items = {'COM1', 'COM5', 'COM2', 'COM3', 'COM4'};
            app.DropDown.ItemsData = [1 2 3 4];
            app.DropDown.Value = 1;
            app.Button = uibutton(app.COMPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Подключиться';
            app.Button_2 = uibutton(app.COMPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Отключиться';
            app.Lamp = uilamp(app.COMPanel);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'θ1'; 'θ2'; 'θ3'; 'θ4'; 'Вращение'; 'Захват'};
            app.UITable.RowName = {};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = 'Запуск';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = 'Остановка';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '-';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = 'Очистить таблицу';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotArm6Dof

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end